classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AntennaTestbedUIFigure          matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        Label_6                         matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minLabel                        matlab.ui.control.Label
        RlimHelpButton                  matlab.ui.control.Button
        maxEditField                    matlab.ui.control.NumericEditField
        RLimOptionCheckBox              matlab.ui.control.CheckBox
        SetMotorsPositionSliderLabel_2  matlab.ui.control.Label
        posh_vell_velaccnLabel          matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        CheckMinimumSuggestedPauseButton  matlab.ui.control.Button
        EditField_14                    matlab.ui.control.NumericEditField
        YourSlelectedFilestocompareTextArea  matlab.ui.control.TextArea
        YourSlelectedFilestocompareTextAreaLabel  matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        MasterThesis2023byChiragPatelLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TurnONthedevicesandsetaconnecitonwithmatlabLabel  matlab.ui.control.Label
        DeleteLabel_2                   matlab.ui.control.Label
        DataImportLabel_2               matlab.ui.control.Label
        EXASettingCheckBox              matlab.ui.control.CheckBox
        PosH_velL_velAccnLabel_2        matlab.ui.control.Label
        ArcusSettingsCheckBox           matlab.ui.control.CheckBox
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        dBmLabel_2                      matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        RangeStepSizePausesecLabel      matlab.ui.control.Label
        StartAutoMeasurementButton      matlab.ui.control.Button
        currentpositionEditField        matlab.ui.control.NumericEditField
        CheckCurrentPositionButton      matlab.ui.control.Button
        StepPauseEditField              matlab.ui.control.NumericEditField
        StepSizeEditField               matlab.ui.control.NumericEditField
        TimesEditField                  matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        SetMoveModeButton               matlab.ui.control.Button
        EditField4_2                    matlab.ui.control.NumericEditField
        EditField3_3                    matlab.ui.control.NumericEditField
        EditField2_3                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        SettheParametersButton          matlab.ui.control.Button
        SetcurrentPositionto0Button     matlab.ui.control.Button
        ResetParametersButton           matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        GettheParametersButton          matlab.ui.control.Button
        AbortOperationButton            matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        NextPeakLeftButton              matlab.ui.control.Button
        NextPeakRightButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AgilentEXA_N9010ASignalAnalyzerLabel  matlab.ui.control.Label
        AgilentMXG_N5182ASignalGeneratorLabel  matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        SetMotorsPositionButton         matlab.ui.control.Button
        SetMotorsPositionSlider         matlab.ui.control.Slider
        dBmLabel                        matlab.ui.control.Label
        Lamp_5                          matlab.ui.control.Lamp
        ARCUSButton                     matlab.ui.control.StateButton
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        FrequencyataMarkerButton        matlab.ui.control.Button
        EditField_5                     matlab.ui.control.NumericEditField
        EditField2_2                    matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        PowerButton                     matlab.ui.control.Button
        FrequencyButton                 matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        MXGButton                       matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        RFOutputButton                  matlab.ui.control.StateButton
        EditField_4                     matlab.ui.control.NumericEditField
        PowerataMarkerButton            matlab.ui.control.Button
        MarkerSettingCheckBox           matlab.ui.control.CheckBox
        EditField5                      matlab.ui.control.NumericEditField
        EditField4                      matlab.ui.control.NumericEditField
        EditField3_2                    matlab.ui.control.NumericEditField
        SpanButton                      matlab.ui.control.Button
        BWButton                        matlab.ui.control.Button
        CenterFreqButton                matlab.ui.control.Button
        EditField3                      matlab.ui.control.NumericEditField
        EditField2                      matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Lamp_4                          matlab.ui.control.Lamp
        MarkeronConstantPeakSearchButton  matlab.ui.control.StateButton
        MarkerButton                    matlab.ui.control.Button
        MarkerOFFButton                 matlab.ui.control.Button
        NextPeakButton                  matlab.ui.control.Button
        ModePresetButton                matlab.ui.control.Button
        AutoTuneButton                  matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        AllMarkersOFFButton             matlab.ui.control.Button
        PeakSearchButton                matlab.ui.control.Button
        EXAButton                       matlab.ui.control.StateButton
    end

    properties (Access = public)
        obj1;
        obj;
        T;
        file_name;
        GHz = 1e9;
        Anlzr;
        MHz = 1e6;
        Gen;
        KHz = 1e3;
        RFOP;
        files;
        paths;
        Hz  = 1;
        Arcu;
        H_vel = 1000; % default high velocity
        L_vel = 300; % default low velocity
        Accn = 300;  % default accelaratiohn
        Rset = "[0;1000;300;300]";
        polarPlot;
        tab_spn;
        tab_freq;
    end
    methods (Access = private)

        function Plot_comp(app)
            usr_max = app.maxEditField.Value;
            usr_min = app.minEditField.Value;

            if iscell(app.files)

                figure
                polarplot(0,0);
                legend(sprintf('Rlim = -%d, %d', usr_min, usr_max));
                ax = gca;
                title('Antenna Radiation Pattern..!');
                ax.ThetaZeroLocation = 'top';
%                 ax.ThetaDir = 'clockwise';
                ax.ThetaColor = 'blue';


                %         % Check if polar plot already exists
                %         if isfield(app, 'polarPlot') && isvalid(app.polarPlot)
                %             ax = app.polarPlot;
                %             delete(findall(ax, 'type', 'polaraxes'));
                %         else
                %             app.polarPlot = polarplot(0,0);
                %             legend("Empty")
                %             ax = gca;
                %             title('Antenna Radiation Pattern..!')
                %             ax.ThetaZeroLocation = 'top';
                %             ax.ThetaColor = 'blue';
                %         end


                mn =  Inf;
                mx = -Inf;

                for i = 1:length(app.files)
                    currentData = readtable(fullfile(app.paths,app.files{i}),'VariableNamingRule','preserve');
                    %                     eval([ 'file_' num2str(i) '= currentData;']);

                    x = table2array(currentData(:,2));
                    y = table2array(currentData(:,3));

                    mn = min(mn,min(y(:))) - usr_min;
                    mx = max(mx,max(y(:))) + usr_max;

                    x1 = deg2rad(x);


                    rlim([mn mx])
                    hold on;
                    polarplot(x1, y,'DisplayName',app.files{i});

%                                         polarplot([x1(end), x1(1)], [y(end), y(1)]);

                    %                     lebal = fielnames{i};
                    %                     polarpattern(x, y, 'TitleTop','Antenna Radiation Pattern',...
                    %                         'AngleAtTop',0, 'AngleDirection','cw','MagnitudeAxisAngle',90,...
                    %                         'LegendLabels',lebal);

                end

                legend('Location','southwestoutside')

            else

                Single_File = readtable(app.files,'VariableNamingRule','preserve');
                x=  table2array(Single_File(:,2));
                y=  table2array(Single_File(:,3));

                mn = min(y) - usr_min;
                mx = max(y) + usr_max;

                x1 = deg2rad(x);


                %         % Check if polar plot already exists
                %         if isfield(app, 'polarPlot') && isvalid(app.polarPlot)
                %             ax = app.polarPlot;
                %             delete(findall(ax, 'type', 'polaraxes'));
                %         else
                %             app.polarPlot = polarplot(0,0);
                %             legend("Empty")
                %             ax = gca;
                %             title('Antenna Radiation Pattern..!')
                %             ax.ThetaZeroLocation = 'top';
                %             ax.ThetaColor = 'blue';
                %         end


                figure
                polarplot(0,0);
                legend(sprintf('Rlim = -%d, %d', usr_min, usr_max));
                ax = gca;
                title('Antenna Radiation Pattern..!');
                ax.ThetaZeroLocation = 'top';
%                 ax.ThetaDir = 'clockwise';
                ax.ThetaColor = 'blue';

                hold on;

                rlim([mn mx])

                polarplot(x1, y,'DisplayName',app.files);
                legend('Location','southeast');
                hold on;
                %                         polarplot([x1(end), x1(1)], [y(end), y(1)])
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MXGButton
        function MXGButtonValueChanged(app, event)
            app.Gen = app.MXGButton.Value;
            if (app.Gen==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.Disconnect_Generator();
                app.Lamp_2.Color='red';
            end
        end

        % Value changed function: RFOutputButton
        function RFOutputButtonValueChanged(app, event)
            app.RFOP = app.RFOutputButton.Value;
            if app.Gen == 1
                if (app.RFOP==1)
                    app.obj1.RF_OutPut_ON();
                    app.Lamp.Color='green';
                else
                    app.obj1.RF_OutPut_OFF();
                    app.Lamp.Color='red';
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["MXG Generator is not connected!!";"Please Switch ON first."],...
                    "Error","custom",L);
            end
        end

        % Value changed function: EXAButton
        function EXAButtonValueChanged(app, event)
            app.Anlzr = app.EXAButton.Value;
            if (app.Anlzr==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.Disconnect_Analyzer();
                app.Lamp_3.Color='red';
            end
        end

        % Button pushed function: PeakSearchButton
        function PeakSearchButtonPushed(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Button pushed function: AllMarkersOFFButton
        function AllMarkersOFFButtonPushed(app, event)
            app.obj.Turn_off_All_Markers();
        end

        % Button pushed function: AutoTuneButton
        function AutoTuneButtonPushed(app, event)
            app.obj.Auto_Tune();
            app.EditField3_2.Value = 3.5;
            app.DropDown_2.Value = app.DropDown_2.Items(1);
            app.EditField4.Value = 3;
            app.DropDown_4.Value = app.DropDown_4.Items(1);
            app.EditField5.Value = 6.5;
            app.DropDown_3.Value = app.DropDown_3.Items(1);
        end

        % Button pushed function: ModePresetButton
        function ModePresetButtonPushed(app, event)
            app.obj.Mode_Preset();
            app.EditField3_2.Value = 3.5;
            app.DropDown_2.Value = app.DropDown_2.Items(1);
            app.EditField4.Value = 3;
            app.DropDown_4.Value = app.DropDown_4.Items(1);
            app.EditField5.Value = 6.5;
            app.DropDown_3.Value = app.DropDown_3.Items(1);
        end

        % Button pushed function: NextPeakButton
        function NextPeakButtonPushed(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Button pushed function: MarkerOFFButton
        function MarkerOFFButtonPushed(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Button pushed function: MarkerButton
        function MarkerButtonPushed(app, event)
            app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: MarkeronConstantPeakSearchButton
        function MarkeronConstantPeakSearchButtonValueChanged(app, event)
            value = app.MarkeronConstantPeakSearchButton.Value;
            if (value==1)
                app.obj.Set_Marker_onPeak(1);
                app.Lamp_4.Color='green';
            else
                app.obj.Turn_off_Marker(1);
                app.Lamp_4.Color='red';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            a = app.EditField_3.Value;
            if app.Anlzr == 1
                if app.Gen == 1
                    app.EditField3_2.Value = a;
                    switch app.DropDown.Value
                        case 'GHz'
                            app.DropDown_2.Value = 'GHz';
                            app.obj1.SetFrequeny(a*app.GHz);
                            app.tab_freq = (a*app.GHz);
                            app.obj.Set_Center_Frequency(a*app.GHz);
                        case 'MHz'
                            app.DropDown_2.Value = 'MHz';
                            app.obj1.SetFrequeny(a*app.MHz);
                            app.tab_freq = (a*app.MHz);
                            app.obj.Set_Center_Frequency(a*app.MHz);
                        case 'KHz'
                            app.DropDown_2.Value = 'KHz';
                            app.obj1.SetFrequeny(a*app.KHz);
                            app.tab_freq = (a*app.KHz);
                            app.obj.Set_Center_Frequency(a*app.KHz);
                        case 'Hz'
                            app.DropDown_2.Value = 'Hz';
                            app.obj1.SetFrequeny(a*app.Hz);
                            app.tab_freq = (a*app.Hz);
                            app.obj.Set_Center_Frequency(a*app.Hz);
                    end
                end
            elseif app.Gen == 1
                switch app.DropDown.Value
                    case 'GHz'
                        app.obj1.SetFrequeny(a*app.GHz);
                    case 'MHz'
                        app.obj1.SetFrequeny(a*app.MHz);
                    case 'KHz'
                        app.obj1.SetFrequeny(a*app.KHz);
                    case 'Hz'
                        app.obj1.SetFrequeny(a*app.Hz);
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["MXG Generator is not connected!!";"Please Switch ON the 'Generator' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            if app.Gen == 1
                app.obj1.SetPowerLevel(app.EditField2_2.Value);
            else
                L = imread("icons8-switches-100.png");
                msgbox(["MXG Generator is not connected!!";...
                    "Please Switch ON the 'Generator' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: CenterFreqButton
        function CenterFreqButtonPushed(app, event)
            a = app.EditField3_2.Value;
            if app.Anlzr == 1
                switch app.DropDown_2.Value
                    case 'GHz'
                        app.obj.Set_Center_Frequency(a*app.GHz);
                    case 'MHz'
                        app.obj.Set_Center_Frequency(a*app.MHz);
                    case 'KHz'
                        app.obj.Set_Center_Frequency(a*app.KHz);
                    case 'Hz'
                        app.obj.Set_Center_Frequency(a*app.Hz);
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["EXA Analyzer is not connected!!";...
                    "Please Switch ON the 'Analyzer' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
            a = app.EditField4.Value;
            switch app.DropDown_4.Value
                case 'GHz'
                    app.obj.Set_BnadWidth(a*app.GHz);
                case 'MHz'
                    app.obj.Set_BnadWidth(a*app.MHz);
                case 'KHz'
                    app.obj.Set_BnadWidth(a*app.KHz);
                case 'Hz'
                    app.obj.Set_BnadWidth(a*app.Hz);
            end
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            a = app.EditField5.Value;
            if app.Anlzr == 1
                switch app.DropDown_3.Value
                    case 'GHz'
                        app.obj.Set_Span(a*app.GHz);
                        app.tab_spn = (a*app.GHz);
                    case 'MHz'
                        app.obj.Set_Span(a*app.MHz);
                        app.tab_spn = (a*app.MHz);
                    case 'KHz'
                        app.obj.Set_Span(a*app.KHz);
                        app.tab_spn = (a*app.KHz);
                    case 'Hz'
                        app.obj.Set_Span(a*app.Hz);
                        app.tab_spn = (a*app.Hz);
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["EXA Analyzer is not connected!!";...
                    "Please Switch ON the 'Analyzer' first."],...
                    "Error","custom",L);
            end
        end

        % Value changed function: MarkerSettingCheckBox
        function MarkerSettingCheckBoxValueChanged(app, event)
            value = app.MarkerSettingCheckBox.Value;
            if app.Anlzr == 1
                switch value
                    case 0
                        app.MarkeronConstantPeakSearchButton.Visible = 'OFF';
                        app.Lamp_4.Visible              = 'OFF';
                        app.MarkerButton.Visible        = 'OFF';
                        app.MarkerOFFButton.Visible     = 'OFF';
                        app.AllMarkersOFFButton.Visible = 'OFF';
                        app.NextPeakButton.Visible      = 'OFF';
                        app.PeakSearchButton.Visible    = 'OFF';
                        app.NextPeakLeftButton.Visible  = 'OFF';
                        app.NextPeakRightButton.Visible = 'OFF';
                        app.EditField.Visible   = 'OFF';
                        app.EditField_2.Visible = 'OFF';
                        app.EditField3.Visible  = 'OFF';
                        app.EditField2.Visible  = 'OFF';
                        app.EditField_10.Visible= 'OFF';
                        app.EditField_9.Visible = 'OFF';
                    case 1
                        app.MarkeronConstantPeakSearchButton.Visible = 'ON';
                        app.Lamp_4.Visible              = 'ON';
                        app.MarkerButton.Visible        = 'ON';
                        app.MarkerOFFButton.Visible     = 'ON';
                        app.AllMarkersOFFButton.Visible = 'ON';
                        app.NextPeakButton.Visible      = 'ON';
                        app.PeakSearchButton.Visible    = 'ON';
                        app.NextPeakLeftButton.Visible  = 'ON';
                        app.NextPeakRightButton.Visible = 'ON';
                        app.EditField.Visible   = 'ON';
                        app.EditField_2.Visible = 'ON';
                        app.EditField3.Visible  = 'ON';
                        app.EditField2.Visible  = 'ON';
                        app.EditField_10.Visible= 'ON';
                        app.EditField_9.Visible = 'ON';
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["EXA Analyzer is not connected!!";...
                    "Please Switch ON the 'Analyzer' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: PowerataMarkerButton
        function PowerataMarkerButtonPushed(app, event)
            app.EditField_4.Value = str2double(app.obj.Get_Marker_Power(app.EditField_5.Value));
        end

        % Button pushed function: FrequencyataMarkerButton
        function FrequencyataMarkerButtonPushed(app, event)
            app.EditField_7.Value = str2double(app.obj.Get_Marker_Freq(app.EditField_6.Value));
        end

        % Value changed function: ARCUSButton
        function ARCUSButtonValueChanged(app, event)
            app.Arcu = app.ARCUSButton.Value;
            if (app.Arcu==1)
                run ("MyArcus.m");
                MyArcus.Enable();
                app.Lamp_5.Color='green';
                MyArcus.resetStage();
                app.EditField_12.Value = 0;
                app.EditField_8.Value  = 0;
                app.SetMotorsPositionSlider.Value = 0;
                app.EditField2_3.Value = app.H_vel;
                app.EditField3_3.Value = app.L_vel;
                app.EditField4_2.Value = app.Accn;
                app.TextArea_2.Value   = app.Rset;
            else
                MyArcus.Disable();
                app.Lamp_5.Color='red';
            end
        end

        % Value changed function: SetMotorsPositionSlider
        function SetMotorsPositionSliderValueChanged(app, event)
            S_Position = app.SetMotorsPositionSlider.Value;
            if (app.Arcu==1)
                MyArcus.PositionTo(S_Position);
                app.EditField_8.Value = S_Position;
            else
                L = imread("icons8-switches-100.png");
                msgbox(["Arcus Motor is not connected!!";...
                    "Please Switch ON the 'Arcus Motor' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: SetMotorsPositionButton
        function SetMotorsPositionButtonPushed(app, event)
            Position = app.EditField_8.Value;
            if (app.Arcu==1)
                MyArcus.PositionTo(Position);
                app.SetMotorsPositionSlider.Value = Position;
            else
                L = imread("icons8-switches-100.png");
                msgbox(["Arcus Motor is not connected!!";...
                    "Please Switch ON the 'Arcus Motor' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: NextPeakLeftButton
        function NextPeakLeftButtonPushed(app, event)
            app.obj.Next_Peake_Left(app.EditField_10.Value);
        end

        % Button pushed function: NextPeakRightButton
        function NextPeakRightButtonPushed(app, event)
            app.obj.Next_Peake_Right(app.EditField_9.Value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Motor_Step_Size = app.EditField_8.Value;
            Marker_Frequency= app.EditField_7.Value;
            Marker_Power    = app.EditField_4.Value;
            new_row = {Motor_Step_Size Marker_Frequency Marker_Power};
            app.UITable.Data = [app.T; new_row];
            app.T = app.UITable.Data;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if size(app.UITable.Data)   > 0
                app.UITable.Data(end,:) = [];
                app.T(end,:)            = [];
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if (app.Arcu==1)
                MyArcus.Stop();
            else
                L = imread("icons8-switches-100.png");
                msgbox(["Arcus Motor is not connected!!";...
                    "Please Switch ON the 'Arcus Motor' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: AbortOperationButton
        function AbortOperationButtonPushed(app, event)
            if app.Arcu == 1
                MyArcus.Abort();
            else
                L = imread("icons8-switches-100.png");
                msgbox(["Arcus Motor is not connected!!";...
                    "Please Switch ON the 'Arcus Motor' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: GettheParametersButton
        function GettheParametersButtonPushed(app, event)
            C = struct2cell(MyArcus.getParams);
            H = cell2mat(C);
            app.TextArea_2.Value = mat2str(H);
        end

        % Button pushed function: ResetParametersButton
        function ResetParametersButtonPushed(app, event)
            MyArcus.resetStage();
            app.EditField_12.Value = 0;
            app.EditField_8.Value  = 0;
            app.SetMotorsPositionSlider.Value = 0;
            app.EditField2_3.Value = app.H_vel;
            app.EditField3_3.Value = app.L_vel;
            app.EditField4_2.Value = app.Accn;
            app.TextArea_2.Value   = app.Rset;
        end

        % Button pushed function: SetcurrentPositionto0Button
        function SetcurrentPositionto0ButtonPushed(app, event)
            MyArcus.BackToZero();
            app.EditField_8.Value  = 0;
            app.SetMotorsPositionSlider.Value = 0;
        end

        % Button pushed function: SettheParametersButton
        function SettheParametersButtonPushed(app, event)
            C = app.EditField_12.Value;
            H = app.EditField2_3.Value;
            I = app.EditField3_3.Value;
            R = app.EditField4_2.Value;
            MyArcus.setParams(C,H,I,R);
            app.EditField_8.Value  = 0;
            app.SetMotorsPositionSlider.Value = 0;
        end

        % Button pushed function: SetMoveModeButton
        function SetMoveModeButtonPushed(app, event)
            MyArcus.setMoveMode(app.EditField_13.Value);
            % set move mode to INC = (1) (incremental) or ABS = (0) (abbsolute)
            % 0 is recommended for default use
        end

        % Button pushed function: CheckCurrentPositionButton
        function CheckCurrentPositionButtonPushed(app, event)
            if (app.Arcu==1)
                app.currentpositionEditField.Value = MyArcus.Current_Pos;
            else
                L = imread("icons8-switches-100.png");
                msgbox(["Arcus Motor is not connected!!";...
                    "Please Switch ON the 'Arcus Motor' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: StartAutoMeasurementButton
        function StartAutoMeasurementButtonPushed(app, event)
            range       = app.TimesEditField.Value;
            resolution  = app.StepSizeEditField.Value;
            seconds     = app.StepPauseEditField.Value;
            usr_max     = app.maxEditField.Value;
            usr_min     = app.minEditField.Value;
            if (app.Gen)*(app.Anlzr)*(app.Arcu) == 1
                if range > 361
                    L = imread("icons8-lol-64.png");
                    msgbox(["range should be less than 360°.";"Please select valid value."],...
                        "Error","custom",L);
                elseif mod(360,resolution) ~= 0
                    new_resolution = round(resolution);
                    if mod(360,new_resolution) ~= 0
                        new_resolution = new_resolution - 1;end
                    L = imread("icons8-lol-64.png");
                    msg = sprintf("The step_size value %.1f is not divisible by 360°.\n\n      Suggested value: %d \n\nOR you must select a valid value.", resolution, new_resolution);
                    msgbox(msg,"Value Error","custom",L);
                    app.StepSizeEditField.Value = new_resolution;
                elseif app.RFOP ~= 1
                    L = imread("icons8-broken-robot-100.png");
                    msg = sprintf("The 'RF Output' button is OFF now.\nPlease turn it ON to continue.");
                    msgbox(msg,"Button Press Error","custom",L);
                else
                    %                     app.UITable.Data = Auto_test(range, resolution, seconds);
                    app.obj = Analyzer_EXA_N9010A();
                    app.obj.Set_Marker_onPeak(1); % In case of following the peak in the selected/entire bandwidth of EXA
                    %                     app.obj.Set_Marker(1) % In case there is a signal in the center of the EXA bandwidth

                    times = range / resolution;

                    C = [times 5];
                    J = {'string','double','double','double','string'};
                    P = ["No.","Step_Size","Power","Freq","Notes"];
                    app.T =  table('Size',C,'VariableTypes',J,'VariableNames',P);

                    figure;
                    polarplot(0,0);
                    title('Antenna Radiation Pattern..!');
                    ax = gca;
                    ax.ThetaZeroLocation = 'top';
                    ax.ThetaColor = 'blue';

                    mn =  Inf;
                    mx = -Inf;

                    for step_times = 1:(times + 1)

                        Step_Size = (step_times - 1) * resolution;


                        MyArcus.PositionTo(Step_Size);   % set psition
                        pause (seconds)

                        Power = str2double(app.obj.Get_Marker_Power(1));
                        Freq  = str2double(app.obj.Get_Marker_Freq(1));

                        app.T(step_times,:) = {step_times,Step_Size, Power, Freq, ''};
                        app.UITable.Data = app.T;

                        pause (0.05)

                        y=  table2array(app.T(:,3));
                        x1 = deg2rad(Step_Size);

                        mn = min(mn,min(y(:))) + 1;
                        mx = max(mx,max(y(:))) - 1;

                        hold on;
                        rlim([mn mx]);
                        polarplot(x1, Power, 'Marker','.');
                    end
                    x = table2array(app.T(:,2));
                    y = table2array(app.T(:,3));
                    x2 = x/180*pi;
                    mn = min(y) - usr_min;
                    mx = max(y) + usr_max;
                    hold on;
                    rlim([mn mx]);
                    polarplot(x2, y);                  
                    hold on;
                    polarplot([x2(end), x2(1)], [y(end), y(1)])
                    %%%%%%%

%                     % Find opening angles for 3 dB drop
%                     power_threshold = max(y) - 3; % Set the power threshold for 3 dB drop
% 
%                     indices = find(y >= power_threshold); % Find indices where power is above the threshold
% 
%                     if numel(indices) >= 2
%                         opening_angle1_radians = x2(indices(1)); % First opening angle in radians
%                         opening_angle2_radians = x2(indices(end)); % Second opening angle in radians
% 
%                         opening_angle1_degrees = rad2deg(opening_angle1_radians); % Convert opening angle to degrees
%                         opening_angle2_degrees = rad2deg(opening_angle2_radians); % Convert opening angle to degrees
% 
%                         % Plot opening angle lines
%                         polarplot([0 opening_angle1_degrees*pi/180], [0 max(y)], 'r', 'LineWidth', 2);
%                         polarplot([0 opening_angle2_degrees*pi/180], [0 max(y)], 'r', 'LineWidth', 2);
%                     else
%                         fprintf('No opening angles found for 3 dB drop in power.\n');
%                     end
% 
%                     % Display opening angles
%                     if ~isnan(opening_angle1_degrees) && ~isnan(opening_angle2_degrees)
%                         fprintf('Opening Angle 1: %.2f degrees\n', opening_angle1_degrees);
%                         fprintf('Opening Angle 2: %.2f degrees\n', opening_angle2_degrees);
%                     end

                    %%%%%%%
                    hold off;
                    
                    app.T(2, 5) = {sprintf('frequency = %d', app.tab_freq)};
                    app.T(3, 5) = {sprintf('Power     = %d', app.EditField2_2.Value)};
                    app.T(4, 5) = {sprintf('Span      = %d', app.tab_spn)};
                    app.T(5, 5) = {sprintf('Range     = %d', app.TimesEditField.Value)};
                    app.T(6, 5) = {sprintf('Step_size = %d', app.StepSizeEditField.Value)};
                    app.T(7, 5) = {sprintf('Pause     = %d Second', app.StepPauseEditField.Value)};
                    app.UITable.Data = app.T;

                    MyArcus.PositionTo(0);
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["MXG, EXA and Arcus are not connected!!";...
                    "   Please Switch ON the";...
                    "'Generator, Analyzer & Arcus' first."],...
                    "Error","custom",L);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            [app.files,app.paths] = uigetfile('*.xlsx','Select Data Files to Compare...','MultiSelect','on');
            if isnumeric(app.files) || isnumeric(app.paths)
                return
            end
            app.YourSlelectedFilestocompareTextArea.Value = app.files;
            Plot_comp(app);

            %             elseif iscell(filenames)
            %                 app.YourSlelectedFilestocompareTextArea.Value = filenames;
            %
            %                 figure
            %                 polarplot(0,0)
            %                 legend("Empty")
            %                 ax = gca;
            %                 title('Antenna Radiation Pattern..!')
            %                 ax.ThetaZeroLocation = 'top';
            %                 ax.ThetaColor = 'blue';
            %
            %                 mn =  Inf;
            %                 mx = -Inf;
            %
            %                 for i = 1:length(filenames)
            %                     currentData = readtable(fullfile(pathname,filenames{i}),'VariableNamingRule','preserve');
            %                     %                     eval([ 'file_' num2str(i) '= currentData;']);
            %
            %                     x = table2array(currentData(:,2));
            %                     y = table2array(currentData(:,3));
            %
            %                     mn = min(mn,min(y(:))) - (app.minEditField.Value);
            %                     mx = max(mx,max(y(:))) + (app.maxEditField.Value);
            %
            %                     x1 = deg2rad(x);
            %
            %                     hold on;
            %
            %                     rlim([mn mx])
            %                     polarplot(x1, y,'DisplayName',filenames{i});
            %
            % %                     polarplot([x1(end), x1(1)], [y(end), y(1)]);
            %
            % %                     lebal = fielnames{i};
            % %                     polarpattern(x, y, 'TitleTop','Antenna Radiation Pattern',...
            % %                         'AngleAtTop',0, 'AngleDirection','cw','MagnitudeAxisAngle',90,...
            % %                         'LegendLabels',lebal);
            %
            %                 end
            %
            %                 legend('Location','southwestoutside')
            %
            %             else
            %                 app.YourSlelectedFilestocompareTextArea.Value = filenames;
            %                 Single_File = readtable(filenames,'VariableNamingRule','preserve');
            %                 x=  table2array(Single_File(:,2));
            %                 y=  table2array(Single_File(:,3));
            %
            %                 mn = min(y) - (app.minEditField.Value);
            %                 mx = max(y) + (app.maxEditField.Value);
            %
            %                 x1 = deg2rad(x);
            %
            %                 figure
            %                 polarplot(0,0)
            %                 legend("Empty")
            %                 ax = gca;
            %                 title('Antenna Radiation Pattern..!')
            %                 ax.ThetaZeroLocation = 'top';
            %                 ax.ThetaColor = 'blue';
            %
            %                 hold on
            %
            %                 rlim([mn mx])
            %
            %                 polarplot(x1, y);
            %                 polarplot([x1(end), x1(1)], [y(end), y(1)])
            %                 legend(filenames,'Location','southeast')
            %
            % %                 hold off
            %             end
        end

        % Value changed function: maxEditField, minEditField
        function minEditFieldValueChanged(app, event)
            myText = app.YourSlelectedFilestocompareTextArea.Value;
            if isnumeric(app.files) || isnumeric(app.paths)
                return
            elseif strcmp(myText, '--Empty')
                return
            else
                Plot_comp(app);
            end
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            app.YourSlelectedFilestocompareTextArea.Value = "--Empty";
            %             app.YourSlelectedFilestocompareTextArea.Value = [];
            clear app
            close all
            clearvars
            clc
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            fileName = datestr(datetime, 'dd mm yy, HH-MM');
            [file, path,~] = uiputfile('*.xlsx','Save Your Data File.....!', fileName);
            if isnumeric(file) || isnumeric(path)
                return
            else
                app.file_name = [path file];
                writetable(app.UITable.Data,app.file_name)
                %             winopen(app.file_name);
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            [file, path,~] = uigetfile('*.xlsx');
            if isnumeric(file) || isnumeric(path)
                return
            else
                app.file_name = [path file];
                app.T = readtable(app.file_name);
                app.UITable.Data = app.T;
            end
        end

        % Value changed function: ArcusSettingsCheckBox
        function ArcusSettingsCheckBoxValueChanged(app, event)
            value = app.ArcusSettingsCheckBox.Value;
            if app.Arcu == 1
                switch value
                    case 0
                        app.SetcurrentPositionto0Button.Visible = 'OFF';
                        app.ResetParametersButton.Visible       = 'OFF';
                        app.SetMoveModeButton.Visible           = 'OFF';
                        app.EditField_13.Visible                = 'OFF';
                        app.GettheParametersButton.Visible      = 'OFF';
                        app.TextArea_2.Visible                  = 'OFF';
                        app.posh_vell_velaccnLabel.Visible      = 'OFF';
                        app.SettheParametersButton.Visible      = 'OFF';
                        app.EditField_12.Visible                = 'OFF';
                        app.EditField2_3.Visible                = 'OFF';
                        app.EditField3_3.Visible                = 'OFF';
                        app.EditField4_2.Visible                = 'OFF';
                        app.PosH_velL_velAccnLabel_2.Visible    = 'OFF';
                        app.SetMotorsPositionSlider.Visible     = 'OFF';
                        app.SetMotorsPositionSliderLabel_2.Visible = 'OFF';
                        app.AbortOperationButton.Visible        = 'OFF';
                        app.StopButton.Visible                  = 'OFF';
                        app.SetMotorsPositionButton.Visible     = 'OFF';
                        app.EditField_8.Visible                 = 'OFF';
                        app.Label.Visible                       = 'OFF';
                        app.CheckCurrentPositionButton.Visible  = 'OFF';
                        app.currentpositionEditField.Visible    = 'OFF';
                        app.Label_3.Visible                     = 'OFF';
                    case 1
                        app.SetcurrentPositionto0Button.Visible = 'ON';
                        app.ResetParametersButton.Visible       = 'ON';
                        app.SetMoveModeButton.Visible           = 'ON';
                        app.EditField_13.Visible                = 'ON';
                        app.GettheParametersButton.Visible      = 'ON';
                        app.TextArea_2.Visible                  = 'ON';
                        app.posh_vell_velaccnLabel.Visible      = 'ON';
                        app.SettheParametersButton.Visible      = 'ON';
                        app.EditField_12.Visible                = 'ON';
                        app.EditField2_3.Visible                = 'ON';
                        app.EditField3_3.Visible                = 'ON';
                        app.EditField4_2.Visible                = 'ON';
                        app.PosH_velL_velAccnLabel_2.Visible    = 'ON';
                        app.SetMotorsPositionSlider.Visible     = 'ON';
                        app.SetMotorsPositionSliderLabel_2.Visible = 'ON';
                        app.AbortOperationButton.Visible        = 'ON';
                        app.StopButton.Visible                  = 'ON';
                        app.SetMotorsPositionButton.Visible     = 'ON';
                        app.EditField_8.Visible                 = 'ON';
                        app.Label.Visible                       = 'ON';
                        app.CheckCurrentPositionButton.Visible  = 'ON';
                        app.currentpositionEditField.Visible    = 'ON';
                        app.Label_3.Visible                     = 'ON';
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["Arcus Motor is not connected!!";...
                    "Please Switch ON the 'Arcus Motor' first."],...
                    "Error","custom",L);
            end
        end

        % Value changed function: EXASettingCheckBox
        function EXASettingCheckBoxValueChanged(app, event)
            value = app.EXASettingCheckBox.Value;
            if app.Anlzr == 1
                switch value
                    case 0
                        app.FrequencyataMarkerButton.Visible         = 'OFF';
                        app.EditField_6.Visible                      = 'OFF';
                        app.EditField_7.Visible                      = 'OFF';
                        app.PowerataMarkerButton.Visible             = 'OFF';
                        app.EditField_5.Visible                      = 'OFF';
                        app.EditField_4.Visible                      = 'OFF';
                        app.dBmLabel.Visible                         = 'OFF';
                        app.Button.Visible                           = 'OFF';
                        app.Button_4.Visible                         = 'OFF';
                        app.DataImportLabel_2.Visible                = 'OFF';
                        app.Button_4.Visible                         = 'OFF';
                        app.DeleteLabel_2.Visible                    = 'OFF';
                        app.AutoTuneButton.Visible                   = 'OFF';
                        app.ModePresetButton.Visible                 = 'OFF';
                        app.BWButton.Visible                         = 'OFF';
                        app.EditField4.Visible                       = 'OFF';
                        app.DropDown_4.Visible                       = 'OFF';
                    case 1
                        app.FrequencyataMarkerButton.Visible         = 'ON';
                        app.EditField_6.Visible                      = 'ON';
                        app.EditField_7.Visible                      = 'ON';
                        app.PowerataMarkerButton.Visible             = 'ON';
                        app.EditField_5.Visible                      = 'ON';
                        app.EditField_4.Visible                      = 'ON';
                        app.dBmLabel.Visible                         = 'ON';
                        app.Button.Visible                           = 'ON';
                        app.Button_4.Visible                         = 'ON';
                        app.DataImportLabel_2.Visible                = 'ON';
                        app.Button_4.Visible                         = 'ON';
                        app.DeleteLabel_2.Visible                    = 'ON';
                        app.AutoTuneButton.Visible                   = 'ON';
                        app.ModePresetButton.Visible                 = 'ON';
                        app.BWButton.Visible                         = 'ON';
                        app.EditField4.Visible                       = 'ON';
                        app.DropDown_4.Visible                       = 'ON';
                end
            else
                L = imread("icons8-switches-100.png");
                msgbox(["EXA Analyzer is not connected!!";...
                    "Please Switch ON the 'Analyzer' first."],...
                    "Error","custom",L);
            end
        end

        % Close request function: AntennaTestbedUIFigure
        function AntennaTestbedUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.AntennaTestbedUIFigure,...
                ["      Closing App?";"";"Please Disconnect 'Arcus, EXA & MXG'."],...
                'Confirm Close', 'Options',{'Close App','Disconnect & Close','Cancel'});
            switch selection
                case 'Close App'
                    delete(app)
                case 'Disconnect & Close'
                    if app.Anlzr == 1
                        app.obj.Auto_Tune();
                        app.obj.Disconnect_Analyzer();
                    end
                    if app.Gen == 1
                        app.obj1.Disconnect_Generator();
                    end
                    if app.Arcu == 1
                        MyArcus.Disable();
                    end
                    delete(app)
                case 'Cancel'
                    return
            end
        end

        % Button pushed function: CheckMinimumSuggestedPauseButton
        function CheckMinimumSuggestedPauseButtonPushed(app, event)
            Pause_Time = ((( 3200 / app.EditField2_3.Value ) * 5 ) + 20) / 360;
            app.EditField_14.Value = (Pause_Time)*(app.StepSizeEditField.Value)+0.2;
            app.StepPauseEditField.Value = app.EditField_14.Value;
        end

        % Value changed function: RLimOptionCheckBox
        function RLimOptionCheckBoxValueChanged(app, event)
            value = app.RLimOptionCheckBox.Value;
            switch value
                case 0
                    app.minEditField.Visible    = 'OFF';
                    app.maxEditField.Visible    = 'OFF';
                    app.RlimHelpButton.Visible  = 'OFF';
                    app.Label_6.Visible         = 'OFF';
                    app.minLabel.Visible        = 'OFF';
                    app.maxLabel.Visible        = 'OFF';
                    app.minEditField.Value      = 1;
                    app.maxEditField.Value      = 1;
                case 1
                    app.minEditField.Visible    = 'ON';
                    app.maxEditField.Visible    = 'ON';
                    app.RlimHelpButton.Visible  = 'ON';
                    app.Label_6.Visible         = 'ON';
                    app.minLabel.Visible        = 'ON';
                    app.maxLabel.Visible        = 'ON';
            end
        end

        % Button pushed function: RlimHelpButton
        function RlimHelpButtonPushed(app, event)
            helpNote = (["Instructions to use Rlim: ";...
                "1. Check & uncheck in box reset Rlim values";...
                "2. If 'Selected Files' box is emptly Rlim will not create new graph";...
                "3. Use menue bar 'reset' button to clear 'Selected Files' box";...
                "4. select files and generate different patterns with different Rlims"]);

            msgbox(helpNote, 'Instructions');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AntennaTestbedUIFigure = uifigure('Visible', 'off');
            app.AntennaTestbedUIFigure.Name = 'Antenna Testbed';
            app.AntennaTestbedUIFigure.Icon = 'icons8-matlab-64.png';
            app.AntennaTestbedUIFigure.CloseRequestFcn = createCallbackFcn(app, @AntennaTestbedUIFigureCloseRequest, true);
            app.AntennaTestbedUIFigure.Tag = 'Chirag Patel';
            app.Toolbar = uitoolbar(app.AntennaTestbedUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Save Table data'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'icons8-save-close-48.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Open Excel File '};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'icons8-open-file-folder-48.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'clear variables and internal data'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'icons8-update-left-rotation-48.png';
            app.EXAButton = uibutton(app.AntennaTestbedUIFigure, 'state');
            app.EXAButton.ValueChangedFcn = createCallbackFcn(app, @EXAButtonValueChanged, true);
            app.EXAButton.Text = 'EXA';
            app.PeakSearchButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.PeakSearchButton.ButtonPushedFcn = createCallbackFcn(app, @PeakSearchButtonPushed, true);
            app.PeakSearchButton.Visible = 'off';
            app.PeakSearchButton.Text = '  Peak Search';
            app.AllMarkersOFFButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.AllMarkersOFFButton.ButtonPushedFcn = createCallbackFcn(app, @AllMarkersOFFButtonPushed, true);
            app.AllMarkersOFFButton.Visible = 'off';
            app.AllMarkersOFFButton.Text = 'All Markers OFF';
            app.Lamp_3 = uilamp(app.AntennaTestbedUIFigure);
            app.AutoTuneButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.AutoTuneButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuneButtonPushed, true);
            app.AutoTuneButton.Visible = 'off';
            app.AutoTuneButton.Text = 'Auto Tune';
            app.ModePresetButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.ModePresetButton.ButtonPushedFcn = createCallbackFcn(app, @ModePresetButtonPushed, true);
            app.ModePresetButton.Visible = 'off';
            app.ModePresetButton.Text = 'Mode Preset';
            app.NextPeakButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.NextPeakButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakButtonPushed, true);
            app.NextPeakButton.Visible = 'off';
            app.NextPeakButton.Text = '  Next Peak';
            app.MarkerOFFButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.MarkerOFFButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerOFFButtonPushed, true);
            app.MarkerOFFButton.Visible = 'off';
            app.MarkerOFFButton.Text = '  Marker OFF';
            app.MarkerButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.MarkerButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerButtonPushed, true);
            app.MarkerButton.Visible = 'off';
            app.MarkerButton.Tooltip = {'Put Marker and'; 'select a number'};
            app.MarkerButton.Text = 'Marker';
            app.MarkeronConstantPeakSearchButton = uibutton(app.AntennaTestbedUIFigure, 'state');
            app.MarkeronConstantPeakSearchButton.ValueChangedFcn = createCallbackFcn(app, @MarkeronConstantPeakSearchButtonValueChanged, true);
            app.MarkeronConstantPeakSearchButton.Visible = 'off';
            app.MarkeronConstantPeakSearchButton.Text = ' Marker on Constant Peak Search ';
            app.Lamp_4 = uilamp(app.AntennaTestbedUIFigure);
            app.Lamp_4.Visible = 'off';
            app.EditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField.Limits = [1 5];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_2.Limits = [1 5];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.EditField_2.Value = 1;
            app.EditField2 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField2.Limits = [1 5];
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Visible = 'off';
            app.EditField2.Value = 1;
            app.EditField3 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField3.Limits = [1 5];
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.EditField3.Visible = 'off';
            app.EditField3.Value = 1;
            app.CenterFreqButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.CenterFreqButton.ButtonPushedFcn = createCallbackFcn(app, @CenterFreqButtonPushed, true);
            app.CenterFreqButton.Text = {'  Center '; '   Freq.'};
            app.BWButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Visible = 'off';
            app.BWButton.Tooltip = {'Bandwidth'};
            app.BWButton.Text = '  BW';
            app.SpanButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = '  Span';
            app.EditField3_2 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField3_2.Tooltip = {'9KHz(9e5) to'; '7GHz(7e9)'};
            app.EditField3_2.Value = 3.5;
            app.EditField4 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField4.Visible = 'off';
            app.EditField4.Value = 3;
            app.EditField5 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField5.Value = 6.5;
            app.MarkerSettingCheckBox = uicheckbox(app.AntennaTestbedUIFigure);
            app.MarkerSettingCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkerSettingCheckBoxValueChanged, true);
            app.MarkerSettingCheckBox.Text = 'Marker Setting';
            app.PowerataMarkerButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.PowerataMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerataMarkerButtonPushed, true);
            app.PowerataMarkerButton.Visible = 'off';
            app.PowerataMarkerButton.Text = {'Power '; 'at a Marker'};
            app.EditField_4 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Visible = 'off';
            app.RFOutputButton = uibutton(app.AntennaTestbedUIFigure, 'state');
            app.RFOutputButton.ValueChangedFcn = createCallbackFcn(app, @RFOutputButtonValueChanged, true);
            app.RFOutputButton.Tag = 'state button on and off';
            app.RFOutputButton.Tooltip = {'Gen. RF Output'};
            app.RFOutputButton.Text = '   RF Output';
            app.Lamp = uilamp(app.AntennaTestbedUIFigure);
            app.MXGButton = uibutton(app.AntennaTestbedUIFigure, 'state');
            app.MXGButton.ValueChangedFcn = createCallbackFcn(app, @MXGButtonValueChanged, true);
            app.MXGButton.Text = 'MXG';
            app.Lamp_2 = uilamp(app.AntennaTestbedUIFigure);
            app.FrequencyButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Tooltip = {'Set Frequency'};
            app.FrequencyButton.Text = 'Frequency';
            app.PowerButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Tooltip = {'Set Power dBm'};
            app.PowerButton.Text = '  Power';
            app.EditField_3 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_3.Tooltip = {'100KHz(1e5) to 6GHz(1e9)'};
            app.EditField_3.Value = 1;
            app.EditField2_2 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField2_2.Limits = [-110 17];
            app.EditField2_2.Tooltip = {'-110dBm to 17dBm'};
            app.EditField2_2.Value = 10;
            app.EditField_5 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_5.Visible = 'off';
            app.EditField_5.Value = 1;
            app.FrequencyataMarkerButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.FrequencyataMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyataMarkerButtonPushed, true);
            app.FrequencyataMarkerButton.Visible = 'off';
            app.FrequencyataMarkerButton.Text = {'Frequency'; 'at a Marker'};
            app.EditField_6 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_6.Visible = 'off';
            app.EditField_6.Value = 1;
            app.EditField_7 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Visible = 'off';
            app.ARCUSButton = uibutton(app.AntennaTestbedUIFigure, 'state');
            app.ARCUSButton.ValueChangedFcn = createCallbackFcn(app, @ARCUSButtonValueChanged, true);
            app.ARCUSButton.Text = {'ARC'; 'US'};
            app.Lamp_5 = uilamp(app.AntennaTestbedUIFigure);
            app.dBmLabel = uilabel(app.AntennaTestbedUIFigure);
            app.dBmLabel.Visible = 'off';
            app.dBmLabel.Text = 'dBm';
            app.SetMotorsPositionSlider = uislider(app.AntennaTestbedUIFigure);
            app.SetMotorsPositionSlider.Limits = [0 360];
            app.SetMotorsPositionSlider.ValueChangedFcn = createCallbackFcn(app, @SetMotorsPositionSliderValueChanged, true);
            app.SetMotorsPositionSlider.Visible = 'off';
            app.SetMotorsPositionButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.SetMotorsPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetMotorsPositionButtonPushed, true);
            app.SetMotorsPositionButton.Visible = 'off';
            app.SetMotorsPositionButton.Text = {'Set Motor''s '; 'Position ( °)'};
            app.EditField_8 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_8.Visible = 'off';
            app.AgilentMXG_N5182ASignalGeneratorLabel = uilabel(app.AntennaTestbedUIFigure);
            app.AgilentMXG_N5182ASignalGeneratorLabel.Text = 'Agilent MXG_N5182A Signal Generator ... . . .';
            app.AgilentEXA_N9010ASignalAnalyzerLabel = uilabel(app.AntennaTestbedUIFigure);
            app.AgilentEXA_N9010ASignalAnalyzerLabel.Text = 'Agilent EXA_N9010A Signal Analyzer........ . . .';
            app.UITable = uitable(app.AntennaTestbedUIFigure);
            app.UITable.ColumnName = {'No.'; 'Position'; 'Power'; 'Freq.'};
            app.UITable.RowName = {};
            app.NextPeakRightButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.NextPeakRightButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakRightButtonPushed, true);
            app.NextPeakRightButton.Visible = 'off';
            app.NextPeakRightButton.Text = {'Next Peak'; '   Right'};
            app.NextPeakLeftButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.NextPeakLeftButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakLeftButtonPushed, true);
            app.NextPeakLeftButton.Visible = 'off';
            app.NextPeakLeftButton.Text = {'Next Peak'; '    Left'};
            app.EditField_9 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_9.Visible = 'off';
            app.EditField_9.Value = 1;
            app.EditField_10 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_10.Visible = 'off';
            app.EditField_10.Value = 1;
            app.Button = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-import-48.png';
            app.Button.Visible = 'off';
            app.Button.Tooltip = {'Import to the Table'};
            app.Button.Text = '';
            app.Button_4 = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'icons8-delete-key-100.png';
            app.Button_4.Visible = 'off';
            app.Button_4.Tooltip = {'Delete last row'};
            app.Button_4.Text = '';
            app.Label = uilabel(app.AntennaTestbedUIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '°';
            app.StopButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.AbortOperationButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.AbortOperationButton.ButtonPushedFcn = createCallbackFcn(app, @AbortOperationButtonPushed, true);
            app.AbortOperationButton.Visible = 'off';
            app.AbortOperationButton.Text = 'Abort Operation';
            app.GettheParametersButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.GettheParametersButton.ButtonPushedFcn = createCallbackFcn(app, @GettheParametersButtonPushed, true);
            app.GettheParametersButton.Visible = 'off';
            app.GettheParametersButton.Text = {'Get the'; 'Parameters'};
            app.TextArea_2 = uitextarea(app.AntennaTestbedUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.ResetParametersButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.ResetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetParametersButtonPushed, true);
            app.ResetParametersButton.Visible = 'off';
            app.ResetParametersButton.Text = 'Reset Parameters';
            app.SetcurrentPositionto0Button = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.SetcurrentPositionto0Button.ButtonPushedFcn = createCallbackFcn(app, @SetcurrentPositionto0ButtonPushed, true);
            app.SetcurrentPositionto0Button.Visible = 'off';
            app.SetcurrentPositionto0Button.Text = 'Set current Position to 0°';
            app.SettheParametersButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.SettheParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SettheParametersButtonPushed, true);
            app.SettheParametersButton.Visible = 'off';
            app.SettheParametersButton.Text = {'Set the'; 'Parameters'};
            app.EditField_12 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_12.Limits = [0 360];
            app.EditField_12.Visible = 'off';
            app.EditField2_3 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField2_3.Limits = [100 10000];
            app.EditField2_3.Visible = 'off';
            app.EditField2_3.Value = 1000;
            app.EditField3_3 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField3_3.Limits = [100 10000];
            app.EditField3_3.Visible = 'off';
            app.EditField3_3.Value = 300;
            app.EditField4_2 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField4_2.Limits = [10 900];
            app.EditField4_2.Visible = 'off';
            app.EditField4_2.Value = 300;
            app.SetMoveModeButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.SetMoveModeButton.ButtonPushedFcn = createCallbackFcn(app, @SetMoveModeButtonPushed, true);
            app.SetMoveModeButton.Visible = 'off';
            app.SetMoveModeButton.Text = 'Set Move Mode';
            app.EditField_13 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.EditField_13.Limits = [0 1];
            app.EditField_13.Visible = 'off';
            app.EditField_13.Tooltip = {'''ABS" = (0) or ''INC'' = (1)'};
            app.TimesEditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.TimesEditField.Tooltip = {'0 to 360'};
            app.StepSizeEditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.StepSizeEditField.Tooltip = {'Range / " Resolution " = accuracy (0.36 - 180 deg.)'};
            app.StepPauseEditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.StepPauseEditField.Tooltip = {'Pause in Seconds'};
            app.CheckCurrentPositionButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.CheckCurrentPositionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckCurrentPositionButtonPushed, true);
            app.CheckCurrentPositionButton.Visible = 'off';
            app.CheckCurrentPositionButton.Tooltip = {'The motor is at this position Now'};
            app.CheckCurrentPositionButton.Text = {'Check Current '; 'Position'};
            app.currentpositionEditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.currentpositionEditField.Visible = 'off';
            app.StartAutoMeasurementButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.StartAutoMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoMeasurementButtonPushed, true);
            app.StartAutoMeasurementButton.Icon = 'icons8-automation-64 (2).png';
            app.StartAutoMeasurementButton.Text = {'Start Auto'; 'Measurement'};
            app.RangeStepSizePausesecLabel = uilabel(app.AntennaTestbedUIFigure);
            app.RangeStepSizePausesecLabel.Text = 'Range, Step Size, Pause(sec)';
            app.DropDown = uidropdown(app.AntennaTestbedUIFigure);
            app.DropDown.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown.Value = 'GHz';
            app.DropDown_2 = uidropdown(app.AntennaTestbedUIFigure);
            app.DropDown_2.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown_2.Value = 'GHz';
            app.dBmLabel_2 = uilabel(app.AntennaTestbedUIFigure);
            app.dBmLabel_2.Text = 'dBm';
            app.DropDown_3 = uidropdown(app.AntennaTestbedUIFigure);
            app.DropDown_3.Items = {'GHz', 'MHz', 'KHz', 'Hz'};
            app.DropDown_3.Value = 'GHz';
            app.DropDown_4 = uidropdown(app.AntennaTestbedUIFigure);
            app.DropDown_4.Items = {'MHz', 'KHz', 'Hz', 'GHz'};
            app.DropDown_4.Visible = 'off';
            app.DropDown_4.Value = 'GHz';
            app.ArcusSettingsCheckBox = uicheckbox(app.AntennaTestbedUIFigure);
            app.ArcusSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArcusSettingsCheckBoxValueChanged, true);
            app.ArcusSettingsCheckBox.Text = 'Arcus Settings';
            app.PosH_velL_velAccnLabel_2 = uilabel(app.AntennaTestbedUIFigure);
            app.PosH_velL_velAccnLabel_2.Visible = 'off';
            app.PosH_velL_velAccnLabel_2.Text = 'Pos ( °);  H_vel;       L_vel;        Accn';
            app.EXASettingCheckBox = uicheckbox(app.AntennaTestbedUIFigure);
            app.EXASettingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EXASettingCheckBoxValueChanged, true);
            app.EXASettingCheckBox.Text = 'EXA Setting';
            app.DataImportLabel_2 = uilabel(app.AntennaTestbedUIFigure);
            app.DataImportLabel_2.Visible = 'off';
            app.DataImportLabel_2.Text = 'Data Import';
            app.DeleteLabel_2 = uilabel(app.AntennaTestbedUIFigure);
            app.DeleteLabel_2.Visible = 'off';
            app.DeleteLabel_2.Text = 'Delete';
            app.TurnONthedevicesandsetaconnecitonwithmatlabLabel = uilabel(app.AntennaTestbedUIFigure);
            app.TurnONthedevicesandsetaconnecitonwithmatlabLabel.Text = 'Turn ON  the devices and set a conneciton with matlab..';
            app.Label_2 = uilabel(app.AntennaTestbedUIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.AntennaTestbedUIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '°';
            app.Image = uiimage(app.AntennaTestbedUIFigure);
            app.Image.ImageSource = '465px-Hochschule_Wismar_logo.svg.png';
            app.MasterThesis2023byChiragPatelLabel = uilabel(app.AntennaTestbedUIFigure);
            app.MasterThesis2023byChiragPatelLabel.Text = {'Master Thesis 2023'; 'by Chirag Patel'};
            app.Label_4 = uilabel(app.AntennaTestbedUIFigure);
            app.Label_4.Text = '°';
            app.Button_5 = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'icons8-compare-80.png';
            app.Button_5.Text = '';
            app.YourSlelectedFilestocompareTextAreaLabel = uilabel(app.AntennaTestbedUIFigure);
            app.YourSlelectedFilestocompareTextAreaLabel.Text = 'Your Slelected Files to compare';
            app.YourSlelectedFilestocompareTextArea = uitextarea(app.AntennaTestbedUIFigure);
            app.YourSlelectedFilestocompareTextArea.Editable = 'off';
            app.YourSlelectedFilestocompareTextArea.Value = {'--Empty'};
            app.EditField_14 = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.CheckMinimumSuggestedPauseButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.CheckMinimumSuggestedPauseButton.ButtonPushedFcn = createCallbackFcn(app, @CheckMinimumSuggestedPauseButtonPushed, true);
            app.CheckMinimumSuggestedPauseButton.Text = {'Check Minimum '; 'Suggested  Pause'};
            app.secLabel = uilabel(app.AntennaTestbedUIFigure);
            app.secLabel.Text = '(sec)';
            app.posh_vell_velaccnLabel = uilabel(app.AntennaTestbedUIFigure);
            app.posh_vell_velaccnLabel.Visible = 'off';
            app.posh_vell_velaccnLabel.Text = '[pos; h_vel; l_vel; accn]';
            app.SetMotorsPositionSliderLabel_2 = uilabel(app.AntennaTestbedUIFigure);
            app.SetMotorsPositionSliderLabel_2.Visible = 'off';
            app.SetMotorsPositionSliderLabel_2.Text = 'Set Motor''s Position';
            app.RLimOptionCheckBox = uicheckbox(app.AntennaTestbedUIFigure);
            app.RLimOptionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RLimOptionCheckBoxValueChanged, true);
            app.RLimOptionCheckBox.Text = 'R-Lim. Option';
            app.maxEditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.maxEditField.Limits = [0 Inf];
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.maxEditField.Visible = 'off';
            app.maxEditField.Value = 1;
            app.RlimHelpButton = uibutton(app.AntennaTestbedUIFigure, 'push');
            app.RlimHelpButton.ButtonPushedFcn = createCallbackFcn(app, @RlimHelpButtonPushed, true);
            app.RlimHelpButton.Visible = 'off';
            app.RlimHelpButton.Text = 'Rlim Help';
            app.minLabel = uilabel(app.AntennaTestbedUIFigure);
            app.minLabel.Visible = 'off';
            app.minLabel.Text = 'min';
            app.minEditField = uieditfield(app.AntennaTestbedUIFigure, 'numeric');
            app.minEditField.Limits = [0 Inf];
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Visible = 'off';
            app.minEditField.Value = 1;
            app.maxLabel = uilabel(app.AntennaTestbedUIFigure);
            app.maxLabel.Visible = 'off';
            app.maxLabel.Text = 'max';
            app.Label_6 = uilabel(app.AntennaTestbedUIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '-';
            % Show the figure after all components are created
            app.AntennaTestbedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AntennaTestbedUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AntennaTestbedUIFigure)
        end
    end
end